classdef Graphe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GrapheLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        HomeButton   matlab.ui.control.Button
        solLabel     matlab.ui.control.Label
        fLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp; 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Home, Adata, bdata, Cdata, choixdata)
            app.callingapp = Home;
            A = Adata;
            b = bdata;
            C = Cdata;
            if strcmp(choixdata,"Min")
                A = -A; b = -b;
            end
            %--------------------------------------------------------------
           
            A=[A;-1 0;0 -1];
            b=[b;0;0];
            
            [n,~]= size(A);
            node = [];
            for i=1:n-1
                  for j=i+1:n
                        M = [A(i,:); A(j,:)];
                        bm = b([i,j]);
                         x = M \ bm;
                        if (A*x <= b)
                          node = [node, x];
        
                        end
                  end
            end
            ax = [0 max(node(1,:))+1 0 max(node(2,:)+1)];
            if strcmp(choixdata,"Min")
                node = [node,[0;max(-b)],[max(-b);0],[max(-b);max(-b)]];
            end
            %tirer les sommet de dolygone
            k = convhull(node(1,:), node(2,:));
            sommet(1,:) = node(1,k)';
            sommet(2,:) = node(2,k)';
            
            %fill the polygone
            fill(app.UIAxes,sommet(1,:), sommet(2,:), 'r','edgecolor', 'b');
            hold(app.UIAxes,'on');
            
            %tracer la fonction en fonction de k
            sol=[];
            for i=1:length(node)
                s= C(1,1)*node(1,i)+ C(1,2)*node(2,i);
                sol = [sol s];
            end
            
            valeur=max(sol);
            
            h = find(sol == valeur);
            indice = h(1);
            solX = node(1,indice);  solY = node(2,indice);
            
            app.solLabel.Text = "Solution = ("+string(solX)+" ; "+string(solY)+")";
           
            app.fLabel.Text = "fobj = "+string(valeur);
            
            x = 0:0.01:max(node(1,:))+1;
            for k =0:valeur/10:valeur
                f = (k-C(1,1)*x)/C(1,2);
                if( k == valeur)
                    plot(app.UIAxes,x,f,'r-');
                    hold(app.UIAxes,'on');
                else
                    plot(app.UIAxes,x,f,'k-');
                    hold(app.UIAxes,'on');
                end
            end
            axis(app.UIAxes,ax);


        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
           app.callingapp.ActivateButtons();
           delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GrapheLabel = uilabel(app.UIFigure);
            app.GrapheLabel.Text = 'Graphe';
            app.UIAxes = uiaxes(app.UIFigure);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.solLabel = uilabel(app.UIFigure);
            app.solLabel.Text = '';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Graphe(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end