classdef Home < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RechercheOperationnellemethodedesimplexeLabel  matlab.ui.control.Label
        AEditFieldLabel  matlab.ui.control.Label
        AField           matlab.ui.control.EditField
        bEditFieldLabel  matlab.ui.control.Label
        bField           matlab.ui.control.EditField
        CEditFieldLabel  matlab.ui.control.Label
        CField           matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        MaxzCxLabel      matlab.ui.control.Label
        StAxbLabel       matlab.ui.control.Label
        TLabel           matlab.ui.control.Label
        x0Label          matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        MinzCxLabel      matlab.ui.control.Label
        StAxbLabel_2     matlab.ui.control.Label
        TLabel_2         matlab.ui.control.Label
        x0Label_2        matlab.ui.control.Label
        simplexeBtn      matlab.ui.control.StateButton
        GraphiqueBtn     matlab.ui.control.StateButton
        AboutBtn         matlab.ui.control.StateButton
        choice           matlab.ui.control.Switch
        simplexestepBtn  matlab.ui.control.StateButton
    end

    properties (Access = private)
        Grapheapp;
        Simplexeapp;% Description
        Simplexestepapp;
        aboutapp;% Description
    end

    methods (Access = public)
    
        function ActivateButtons(app)
            app.AboutBtn.Enable = 'on';
            app.simplexeBtn.Enable = 'on';
            app.simplexestepBtn.Enable = 'on';
            app.GraphiqueBtn.Enable = 'on';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             global HomeApp = app.;
            app.GraphiqueBtn.Enable = 'on';
            app.AField.Value = num2str('3 9;4 5;2 1');
            app.bField.Value = num2str('81;55;20');
            app.CField.Value = num2str('6 4');
        end

        % Value changed function: simplexeBtn
        function simplexeBtnValueChanged(app, event)
            A = num2str(app.AField.Value);
            b = num2str(app.bField.Value);
            C = num2str(app.CField.Value);
            choix = app.choice.Value;
            app.Simplexeapp = simplexe(app,A,b,C,choix);
            app.simplexeBtn.Enable = 'off';
            app.GraphiqueBtn.Enable = 'off';
            app.simplexestepBtn.Enable = 'off';
            app.AboutBtn.Enable = 'off';
        end

        % Value changed function: choice
        function choiceValueChanged(app, event)
 
        end

        % Value changed function: GraphiqueBtn
        function GraphiqueBtnValueChanged(app, event)
            app.GraphiqueBtn.Enable = 'off';
            app.simplexeBtn.Enable = 'off';
            app.simplexestepBtn.Enable = 'off';
            app.AboutBtn.Enable = 'off';
            A = str2num(app.AField.Value);
            b = str2num(app.bField.Value);
            C = str2num(app.CField.Value);
            choix = app.choice.Value;
            app.Grapheapp = Graphe(app,A,b,C,choix);
        end

        % Value changing function: AField
        function AFieldValueChanging(app, event)
          
            
        end

        % Value changed function: AField
        function AFieldValueChanged(app, event)
            A = str2num(app.AField.Value);
            [~,c] = size(A);
            if c == 2
                app.GraphiqueBtn.Enable = 'on';
            else
                app.GraphiqueBtn.Enable = 'off';
            end
        end

        % Value changed function: simplexestepBtn
        function simplexestepBtnValueChanged(app, event)
            A = num2str(app.AField.Value);
            b = num2str(app.bField.Value);
            C = num2str(app.CField.Value);
            choix = app.choice.Value;
            app.Simplexestepapp = simplexestep(app,A,b,C,choix);
            app.simplexeBtn.Enable = 'off';
            app.GraphiqueBtn.Enable = 'off';
            app.simplexestepBtn.Enable = 'off';
            app.AboutBtn.Enable = 'off';
        end

        % Value changed function: AboutBtn
        function AboutBtnValueChanged(app, event)
             app.aboutapp = About(app);
             app.AboutBtn.Enable = 'off';
             app.simplexeBtn.Enable = 'off';
            app.GraphiqueBtn.Enable = 'off';
            app.simplexestepBtn.Enable = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RechercheOperationnellemethodedesimplexeLabel = uilabel(app.UIFigure);
            app.RechercheOperationnellemethodedesimplexeLabel.Text = 'Recherche Operationnelle : methode de simplexe';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AField = uieditfield(app.UIFigure, 'text');
            app.AField.ValueChangedFcn = createCallbackFcn(app, @AFieldValueChanged, true);
            app.AField.ValueChangingFcn = createCallbackFcn(app, @AFieldValueChanging, true);
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bField = uieditfield(app.UIFigure, 'text');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '{';
            app.MaxzCxLabel = uilabel(app.UIFigure);
            app.MaxzCxLabel.Text = 'Max z = C x';
            app.StAxbLabel = uilabel(app.UIFigure);
            app.StAxbLabel.Text = 'St : Ax < b';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T';
            app.x0Label = uilabel(app.UIFigure);
            app.x0Label.Text = 'x>0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '{';
            app.MinzCxLabel = uilabel(app.UIFigure);
            app.MinzCxLabel.Text = 'Min z = C x';
            app.StAxbLabel_2 = uilabel(app.UIFigure);
            app.StAxbLabel_2.Text = 'St : Ax > b';
            app.TLabel_2 = uilabel(app.UIFigure);
            app.TLabel_2.Text = 'T';
            app.x0Label_2 = uilabel(app.UIFigure);
            app.x0Label_2.Text = 'x>0';
            app.simplexeBtn = uibutton(app.UIFigure, 'state');
            app.simplexeBtn.ValueChangedFcn = createCallbackFcn(app, @simplexeBtnValueChanged, true);
            app.simplexeBtn.Text = 'Simplexe Immediate';
            app.GraphiqueBtn = uibutton(app.UIFigure, 'state');
            app.GraphiqueBtn.ValueChangedFcn = createCallbackFcn(app, @GraphiqueBtnValueChanged, true);
            app.GraphiqueBtn.Enable = 'off';
            app.GraphiqueBtn.Text = 'Graphique';
            app.AboutBtn = uibutton(app.UIFigure, 'state');
            app.AboutBtn.ValueChangedFcn = createCallbackFcn(app, @AboutBtnValueChanged, true);
            app.AboutBtn.Text = 'About';
            app.choice = uiswitch(app.UIFigure, 'slider');
            app.choice.Items = {'Max', 'Min'};
            app.choice.ValueChangedFcn = createCallbackFcn(app, @choiceValueChanged, true);
            app.choice.Value = 'Max';
            app.simplexestepBtn = uibutton(app.UIFigure, 'state');
            app.simplexestepBtn.ValueChangedFcn = createCallbackFcn(app, @simplexestepBtnValueChanged, true);
            app.simplexestepBtn.Text = 'Simplexe Step By Step';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Home

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end