classdef simplexe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SimplexeLabel        matlab.ui.control.Label
        UIFirstTable         matlab.ui.control.Table
        UILastTable          matlab.ui.control.Table
        FirstIterationLabel  matlab.ui.control.Label
        LastIterationLabel   matlab.ui.control.Label
        SolutionField        matlab.ui.control.EditField
        HomeButton           matlab.ui.control.Button
        TypeOfSol            matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp;
        A;
        b;
        C;          % Description
        choix;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Home, Adata, bdata, Cdata, choixdata)
            app.callingapp = Home;
            app.A = str2num(Adata);
            app.b = str2num(bdata);
            app.C = str2num(Cdata);
            if strcmp(choixdata,"Max")
                 app.choix = 1;
            else
                app.choix = 0;
            end
%           ------------------------------------------------
            [mat1,mat2,B1,B2,message,sol] = Tabulaire(app.A,app.b,app.C,app.choix);
            solution = strcat("Solution = (",num2str(sol),")");
            arr1 = getText(mat1,B1,app.choix);
            arr2 = getText(mat2,B2,app.choix);
            app.UIFirstTable.Data = split(arr1,"|");
            app.UILastTable.Data = split(arr2,"|");
            
            app.SolutionField.Value = solution;
            app.TypeOfSol.Text = message;
            
%----------------------------------------------------------------------------
%           app.UIFirstTable.Data = num2str(Aa);
%             arr = array2table(Aa);
            %arr = {'hello';'ismail';'ben';'alla'};
%             ------------------------------------------------
            %app.TextArea.Value = cellstr(num2str(app.A));
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.callingapp.ActivateButtons();
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SimplexeLabel = uilabel(app.UIFigure);
            app.SimplexeLabel.Text = 'Simplexe';
            app.UIFirstTable = uitable(app.UIFigure);
            app.UIFirstTable.ColumnName = '';
            app.UIFirstTable.RowName = {};
            app.UILastTable = uitable(app.UIFigure);
            app.UILastTable.ColumnName = '';
            app.UILastTable.RowName = {};
            app.FirstIterationLabel = uilabel(app.UIFigure);
            app.FirstIterationLabel.Text = 'First Iteration';
            app.LastIterationLabel = uilabel(app.UIFigure);
            app.LastIterationLabel.Text = 'Last Iteration';
            app.SolutionField = uieditfield(app.UIFigure, 'text');
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.TypeOfSol = uilabel(app.UIFigure);
            app.TypeOfSol.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = simplexe(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end