classdef simplexestep < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SimplexestepbystepLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        NextIterationButton      matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        Message                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        HomeButton               matlab.ui.control.Button
        TypeOfSol                matlab.ui.control.Label
    end


    properties (Access = private)
        callingapp;
        MyA;
        Myb;
        MyC;
        choix;% Description
        Mat;
        Sol;
        Base;
        Iter;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Home, Adata, bdata, Cdata, choixdata)
             app.callingapp = Home;
             app.MyA = str2num(Adata);
             app.Myb = str2num(bdata);
             app.MyC = str2num(Cdata);
             app.Iter = 0;
             if strcmp(choixdata,"Max")
                 app.choix = 1;
             else
                 app.choix = 0;
             end
             
             [app.Mat,app.Base,app.Sol,mess] = SimplexeInitial(app.MyA, app.Myb, app.MyC, app.choix);
             arr = getText(app.Mat,app.Base,app.choix);
             app.UITable.Data = split(arr,"|");
%              app.UITable.Data = app.MyC;
             Solution = strcat("Solution = (",num2str(app.Sol),")");
             app.EditField.Value = Solution;
             
             
             if strcmp(mess,"")
                 app.Message.Text = "we didn't reach the optimal solution yet";
             else
                 app.Message.Text = mess;
                 app.NextIterationButton.Enable = 'off';
                 app.EditField.Value = "";
             end
        end

        % Button pushed function: NextIterationButton
        function NextIterationButtonPushed(app, event)
            [app.Mat,app.Base,mess,app.Sol,stop] = TabulaireStep(app.Mat,app.Base,app.Sol);
            arr = getText(app.Mat,app.Base,app.choix);
            app.UITable.Data = split(arr,"|");
            Solution = strcat("Solution = (",num2str(app.Sol),")");
            app.EditField.Value = Solution;
            
            app.Iter = app.Iter + 1;
            app.Label.Text = string(app.Iter);
            
            if stop == 1
                app.NextIterationButton.Enable = 'off';
                app.Message.Text = "Well done";
                app.TypeOfSol.Text = mess;
            else
                app.Message.Text = "we didn't reach the optimal solution yet";
            end
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.callingapp.ActivateButtons();
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SimplexestepbystepLabel = uilabel(app.UIFigure);
            app.SimplexestepbystepLabel.Text = 'Simplexe step by step';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.NextIterationButton = uibutton(app.UIFigure, 'push');
            app.NextIterationButton.ButtonPushedFcn = createCallbackFcn(app, @NextIterationButtonPushed, true);
            app.NextIterationButton.Text = 'Next Iteration';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Message = uilabel(app.UIFigure);
            app.Message.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.TypeOfSol = uilabel(app.UIFigure);
            app.TypeOfSol.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = simplexestep(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end